classdef gas_stove_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Knob                    matlab.ui.control.DiscreteKnob
        KnobLabel               matlab.ui.control.Label
        StopTimeEditField       matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel  matlab.ui.control.Label
        TimeScope2              matlab.ui.scope.TimeScope
        TimeScope_3             matlab.ui.scope.TimeScope
        TimeScope_2             matlab.ui.scope.TimeScope
        TimeScope               matlab.ui.scope.TimeScope
        SimulationProgress      simulink.ui.control.SimulationProgress
        SimulationControls      simulink.ui.control.SimulationControls
        Panel                   matlab.ui.container.Panel
        FlowRateFreqeLabel      matlab.ui.control.Label
        AmplitudeKnob_2         matlab.ui.control.Knob
        FlowRateAmpliLabel      matlab.ui.control.Label
        FlowRateBiasLabel       matlab.ui.control.Label
        AmplitudeKnob           matlab.ui.control.Knob
        BiasKnob                matlab.ui.control.Knob
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            value = app.StopTimeEditField.Value;
            % In your GUI code (e.g., a button callback)
            set_param('Pipeline','StopTime',num2str(value+100));
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
           
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            modelName = 'Pipeline'; % Replace with your Simulink model name
            variableName = 'knob';
            variableValue = value; % Get value from a GUI edit box
            % In your GUI code (e.g., a button callback)
            blockPath = 'Pipeline/Constant1'; % Path to the Simulink block
            newGain = value; % Example: Increase the gain
            set_param(blockPath, 'Value', num2str(newGain)); % Set new gain
            set_param(modelName, 'SimulationCommand', 'update') % Update the model
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'hot');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.BiasKnob = uiknob(app.Panel, 'continuous');
            app.BiasKnob.Value = 10;
            app.AmplitudeKnob = uiknob(app.Panel, 'continuous');
            app.AmplitudeKnob.Value = 10;
            app.FlowRateBiasLabel = uilabel(app.Panel);
            app.FlowRateBiasLabel.Text = 'Flow Rate Bias';
            app.FlowRateAmpliLabel = uilabel(app.Panel);
            app.FlowRateAmpliLabel.Text = 'Flow Rate Ampli';
            app.AmplitudeKnob_2 = uiknob(app.Panel, 'continuous');
            app.AmplitudeKnob_2.Limits = [0 10];
            app.AmplitudeKnob_2.Value = 1;
            app.FlowRateFreqeLabel = uilabel(app.Panel);
            app.FlowRateFreqeLabel.Text = 'Flow Rate Freqe';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Sine Wave:1'};
            app.TimeScope.Title = 'Desired input';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.LegendNames = {'PS-Simulink Converter1:1'};
            app.TimeScope_2.Title = 'Actual flow rate';
            app.TimeScope_2.YLabel = 'Amplitude';
            app.TimeScope_3 = uitimescope(app.UIFigure);
            app.TimeScope_3.LegendNames = {'Burner:1'};
            app.TimeScope_3.Title = 'Flame temperature';
            app.TimeScope_3.YLabel = 'Amplitude';
            app.TimeScope_3.YLimits = [0 2000];
            app.TimeScope_3.YLimitsMethod = 'manual';
            app.TimeScope_3.XTimeUnits = 'auto';
            app.TimeScope2 = uitimescope(app.UIFigure);
            app.TimeScope2.LegendNames = {'Vessel:1'};
            app.TimeScope2.LegendVisible = 'on';
            app.TimeScope2.Title = 'Temperature (water)';
            app.TimeScope2.YLabel = 'Amplitude';
            app.TimeScope2.XLimits = [0 1000];
            app.TimeScope2.XTimeSpan = 1000;
            app.StopTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StopTimeEditFieldLabel.Text = 'st_time';
            app.StopTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Value = 1000;
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Off', 'High'};
            app.Knob.ItemsData = [0 1];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Burner:1'], app.TimeScope_3);
            bind(app.AmplitudeKnob, 'Value', app.Simulation.TunableVariables, ['flowR_in_A:' app.Simulation.ModelName]);
            bind(app.BiasKnob, 'Value', app.Simulation.TunableVariables, ['flowR_in_B:' app.Simulation.ModelName]);
            bind(app.AmplitudeKnob_2, 'Value', app.Simulation.TunableVariables, ['flowR_in_f:' app.Simulation.ModelName]);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/PS-Simulink Converter1:1'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Sine Wave:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Vessel:1'], app.TimeScope2);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gas_stove_ui

            % Associate the Simulink Model
            app.Simulation = simulation('Pipeline');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end